classdef Entregable3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        PlacaPositivaEditFieldLabel  matlab.ui.control.Label
        PlacaPositiva                matlab.ui.control.NumericEditField
        PlacaNegativaEditFieldLabel  matlab.ui.control.Label
        PlacaNegativa                matlab.ui.control.NumericEditField
        GraficarcomovectoresunitariosButton  matlab.ui.control.Button
        LongituddelasplacasxLabel    matlab.ui.control.Label
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        PuntoenelplanoLabel          matlab.ui.control.Label
        CampoelctricoenelpuntoLabel  matlab.ui.control.Label
        ExEditFieldLabel             matlab.ui.control.Label
        ExEditField                  matlab.ui.control.NumericEditField
        EyEditFieldLabel             matlab.ui.control.Label
        EyEditField                  matlab.ui.control.NumericEditField
        x6Label                      matlab.ui.control.Label
        GraficarconmagnitudvariableButton  matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % GraficarcomovectoresunitariosButton
        function GraficarcomovectoresunitariosButtonPushed(app, event)
% Longitudes de las placas
    longitud_1 = app.PlacaPositiva.Value
    longitud_2 = app.PlacaNegativa.Value
            
% Graficación de las placas   
    rectangle(app.UIAxes,'Position',[-2 1.45 longitud_1 0.1],'FaceColor',[0.6350, 0.0780, 0.1840])
    rectangle(app.UIAxes,'Position',[-2 -1.45 longitud_2 0.1],'FaceColor',[0.25, 0.25, 0.25])
    xlim (app.UIAxes, [-4 4]);
    ylim (app.UIAxes, [-4 4]);
    hold(app.UIAxes,"on")

% Numero de flechas
    N_flechas = 50;

% Número de cargas en las placas
    N_cargas_1 = round(longitud_1 * 50);
    N_cargas_2 = round(longitud_2 * 50);
    N_cargas_total = N_cargas_1 + N_cargas_2
   
% Cargas puntuales en las placas en Coulombs   
    Carga = 1;

% Constante
    k = 9*10^9;

% Dimensiones del plano
    minX = -4;  
    maxX =  4;
    minY = -4;
    maxY =  4;
    
% Posición de las cargas
   x1_1 = -2; 
   x2_1 = -2 + longitud_1;
   y_1 = 1.5; 


   x1_2 = -2; 
   x2_2 = -2 + longitud_2;
   y_2 = -1.5; 
   

% Ubicación de las cargas
    Cargas_puntuales = Carga .* ones(1,N_cargas_total);
    Cargas_puntuales(1+N_cargas_1:N_cargas_total) = -Carga;
    xC(1:N_cargas_1) = linspace(x1_1,x2_1,N_cargas_1);
    xC(1+N_cargas_1:N_cargas_total) = linspace(x1_2,x2_2,N_cargas_2);
    yC(1:N_cargas_1) = y_1; 
    yC(1+N_cargas_1:N_cargas_total) = y_2;
  
% Inicialización de los campos eléctricos
    Ex = zeros(N_flechas,N_flechas); Ey = zeros(N_flechas,N_flechas);
    
% Ubicación de las flechas en una fila y columna
    x  = linspace(minX,maxX,N_flechas);
    y = linspace(minY, maxY,N_flechas);
   
% Matrices con ubicación en x y y de todas las flechas del plano
    [xF, yF] = meshgrid(x,y);
   
% Sumatoria de campos eléctricos

    for n = 1 : N_cargas_total
            Dx = xF - xC(n);
            Dy = yF - yC(n);
   
        D = sqrt(Dx.^2 + Dy.^2);
   
        D3 = D.^3;
        Ex = Ex + k .* Cargas_puntuales(n) .* Dx ./ D3;
        Ey = Ey + k .* Cargas_puntuales(n) .* Dy ./ D3;
    end

% Campo eléctrico en el punto dado por el usuario
    p_x = app.xEditField.Value
    p_y = app.yEditField.Value
    p_Ex = 0
    p_Ey = 0
    
    for n = 1 : N_cargas_total
            p_Dx = p_x - xC(n);
            p_Dy = p_y - yC(n);
   
            p_D = sqrt(p_Dx^2 + p_Dy^2);
   
            p_D3 = p_D.^3;
            p_Ex = p_Ex + k * Cargas_puntuales(n) * p_Dx / p_D3;
            p_Ey = p_Ey + k * Cargas_puntuales(n) * p_Dy / p_D3;
    end
    app.ExEditField.Value = p_Ex
    app.EyEditField.Value = p_Ey
    

% Módulo del campo eléctrico en x y y
   E = sqrt(Ex.^2 + Ey.^2);

% Graficación de las flechas
   quiver(app.UIAxes,xF,yF,Ex./E,Ey./E,"Color",[0.3010, 0.7450, 0.9330],"LineWidth",0.75);
  

        end

        % Button pushed function: GraficarconmagnitudvariableButton
        function GraficarconmagnitudvariableButtonPushed(app, event)
% Longitudes de las placas
    longitud_1 = app.PlacaPositiva.Value
    longitud_2 = app.PlacaNegativa.Value
            
% Graficación de las placas   
    rectangle(app.UIAxes,'Position',[-2 1.45 longitud_1 0.1],'FaceColor',[0.6350, 0.0780, 0.1840])
    rectangle(app.UIAxes,'Position',[-2 -1.45 longitud_2 0.1],'FaceColor',[0.25, 0.25, 0.25])
    xlim (app.UIAxes, [-4 4]);
    ylim (app.UIAxes, [-4 4]);
    hold(app.UIAxes,"on")

% Numero de flechas
    N_flechas = 50;

% Número de cargas en las placas
    N_cargas_1 = longitud_1 * 50;
    N_cargas_2 = longitud_2 * 50;
    N_cargas_total = N_cargas_1 + N_cargas_2
   
% Cargas puntuales en las placas en Coulombs   
    Carga = 1;

% Constante
    k = 9*10^9;

% Dimensiones del plano
    minX = -4;  
    maxX =  4;
    minY = -4;
    maxY =  4;
    
% Posición de las cargas
   x1_1 = -2; 
   x2_1 = -2 + longitud_1;
   y_1 = 1.5; 


   x1_2 = -2; 
   x2_2 = -2 + longitud_2;
   y_2 = -1.5; 
   

% Ubicación de las cargas
    Cargas_puntuales = Carga .* ones(1,N_cargas_total);
    Cargas_puntuales(1+N_cargas_1:N_cargas_total) = -Carga;
    xC(1:N_cargas_1) = linspace(x1_1,x2_1,N_cargas_1);
    xC(1+N_cargas_1:N_cargas_total) = linspace(x1_2,x2_2,N_cargas_2);
    yC(1:N_cargas_1) = y_1; 
    yC(1+N_cargas_1:N_cargas_total) = y_2;
  
% Inicialización de los campos eléctricos
    Ex = zeros(N_flechas,N_flechas); Ey = zeros(N_flechas,N_flechas);
    
% Ubicación de las flechas en una fila y columna
    x  = linspace(minX,maxX,N_flechas);
    y = linspace(minY, maxY,N_flechas);
   
% Matrices con ubicación en x y y de todas las flechas del plano
    [xF, yF] = meshgrid(x,y);
   
% Sumatoria de campos eléctricos

    for n = 1 : N_cargas_total
            Dx = xF - xC(n);
            Dy = yF - yC(n);
   
        D = sqrt(Dx.^2 + Dy.^2);
   
        D3 = D.^3;
        Ex = Ex + k .* Cargas_puntuales(n) .* Dx ./ D3;
        Ey = Ey + k .* Cargas_puntuales(n) .* Dy ./ D3;
    end

% Campo eléctrico en el punto dado por el usuario
    p_x = app.xEditField.Value
    p_y = app.yEditField.Value
    p_Ex = 0
    p_Ey = 0
    
    for n = 1 : N_cargas_total
            p_Dx = p_x - xC(n);
            p_Dy = p_y - yC(n);
   
            p_D = sqrt(p_Dx.^2 + p_Dy.^2);
   
            p_D3 = p_D.^3;
            p_Ex = p_Ex + k * Cargas_puntuales(n) * p_Dx / p_D3;
            p_Ey = p_Ey + k * Cargas_puntuales(n) * p_Dy / p_D3;
    end
    app.ExEditField.Value = p_Ex
    app.EyEditField.Value = p_Ey
    

% Módulo del campo eléctrico en x y y
   E = sqrt(Ex.^2 + Ey.^2);

% Graficación de las flechas
   quiver(app.UIAxes,xF,yF,Ex,Ey,"Color",[0.3010, 0.7450, 0.9330],"LineWidth",0.75);            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {253, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlacaPositivaEditFieldLabel = uilabel(app.LeftPanel);
            app.PlacaPositivaEditFieldLabel.Text = 'Placa Positiva';
            app.PlacaPositiva = uieditfield(app.LeftPanel, 'numeric');
            app.PlacaNegativaEditFieldLabel = uilabel(app.LeftPanel);
            app.PlacaNegativaEditFieldLabel.Text = 'Placa Negativa';
            app.PlacaNegativa = uieditfield(app.LeftPanel, 'numeric');
            app.GraficarcomovectoresunitariosButton = uibutton(app.LeftPanel, 'push');
            app.GraficarcomovectoresunitariosButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarcomovectoresunitariosButtonPushed, true);
            app.GraficarcomovectoresunitariosButton.Text = 'Graficar como vectores unitarios';
            app.LongituddelasplacasxLabel = uilabel(app.LeftPanel);
            app.LongituddelasplacasxLabel.Text = 'Longitud de las placas(x):';
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PuntoenelplanoLabel = uilabel(app.LeftPanel);
            app.PuntoenelplanoLabel.Text = 'Punto en el plano:';
            app.CampoelctricoenelpuntoLabel = uilabel(app.LeftPanel);
            app.CampoelctricoenelpuntoLabel.Text = 'Campo eléctrico en el punto:';
            app.ExEditFieldLabel = uilabel(app.LeftPanel);
            app.ExEditFieldLabel.Text = 'Ex';
            app.ExEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ExEditField.Editable = 'off';
            app.EyEditFieldLabel = uilabel(app.LeftPanel);
            app.EyEditFieldLabel.Text = 'Ey';
            app.EyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EyEditField.Editable = 'off';
            app.x6Label = uilabel(app.LeftPanel);
            app.x6Label.Text = '0 < x < 6';
            app.GraficarconmagnitudvariableButton = uibutton(app.LeftPanel, 'push');
            app.GraficarconmagnitudvariableButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarconmagnitudvariableButtonPushed, true);
            app.GraficarconmagnitudvariableButton.Text = 'Graficar con magnitud variable';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Campo Eléctrico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-4 4];
            app.UIAxes.YLim = [-4 4];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Entregable3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end