classdef Entregable3App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        AlturadecaidalibreLabel         matlab.ui.control.Label
        h                               matlab.ui.control.NumericEditField
        PasodelosmtodosLabel            matlab.ui.control.Label
        p                               matlab.ui.control.NumericEditField
        GraficarButton                  matlab.ui.control.Button
        ErrormximoRK4EditFieldLabel     matlab.ui.control.Label
        ErrormximoRK4                   matlab.ui.control.NumericEditField
        ErrormximoEulerEditFieldLabel   matlab.ui.control.Label
        ErrormximoEuler                 matlab.ui.control.NumericEditField
        ErrormximoRKHeunEditFieldLabel  matlab.ui.control.Label
        ErrormximoRKHeun                matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        VelocidadFinalLabel             matlab.ui.control.Label
        VelocidadFinal                  matlab.ui.control.NumericEditField
        AceleracinmximaEditFieldLabel   matlab.ui.control.Label
        AceleracionMaxima               matlab.ui.control.NumericEditField
        JerkmximoEditFieldLabel         matlab.ui.control.Label
        JerkMaximo                      matlab.ui.control.NumericEditField
        VelicidadFinalkmh               matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        EditField_5                     matlab.ui.control.EditField
        VelocidadFinalCadaLibreEditFieldLabel  matlab.ui.control.Label
        VelocidadFinalCaidaLibre        matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
% Reto: Entregable 1 
% A01657439 Carlos David Toapanta Noroña
% A01377098 Eva Denisse Vargas Sosa 
% A01378360 Brenda Vega Méndez

g = 9.81; %Gravedad
m = 600; % Masa
r = 3.5; % Radio de la góndola
L = 2; % Longitud del campo magnético 
B = 0.5; % Campo magnético 
ro = 0.0171; % Resistividad
a = (8*r.^2*L*B.^2)/(ro*3.14); % alpha

h = 25; % Altura de caída libre
tc = sqrt(2*h/g) % Tiempo de cambio de caída libre a frenado magnético. 
p = 0.1; % Pasos a utilizar en los métodos  

%Inicialización de variables para los métodos
t = [tc:p:10];
%Variables de Método de Runge Kutta - Heun
v_RKH = zeros(1,length(t));
v_RKH(1) = sqrt(2*g*h);
%Variables de Método de Euler
v_E = zeros(1,length(t));
v_E(1) = sqrt(2*g*h);
%Variables de Método de Runge Kutta de 4to Orden.
v_RK4 = zeros(1,length(t));
v_RK4(1) = sqrt(2*g*h);
% Errores
e_RKH = zeros(1,length(t));
e_E = zeros(1,length(t));
e_RK4 = zeros(1,length(t));
v_CL = @(t) g*t; % Velocidad de la caída libre
altura = zeros(1,length(t))
altura(1) = 50-h;
altura_CL = @(t) 50-1/2*g*t.^2;% Altura de caída libre 
% Tiempos 
t0 = linspace(0,tc);%Caída libre
t2 = linspace(tc,10,length(altura));%Frenado magnético 

% Solución analítica
velocidad = @(t) -(-sqrt(2*g*h)*exp((-a*t/m))+(m*g/a)*(exp(-a*t/m)-1));
% Ecuación Diferencial 
f = @(v) g-(a/m)*v;
% Derivada de velocidad 
aceleracion = @(t) (exp(-a*t/m)*(g*m-a*sqrt(2)*sqrt(g*h)))/m %Respecto al frenado 
aceleracion_CL = ones(length(t0)).*g % Respecto a la caída libre 
%Derivada de la aceleración 
jerk = @(t) (a*exp(-a*t/m)*(a*sqrt(2)*sqrt(g*h)-g*m))/m^2

%Metodo de Runge Kutta-Heun
for i = [2:length(t)]
    k1 = f(v_RKH(i-1))
    k2 = f(v_RKH(i-1)+p);
    v_RKH(i) = v_RKH(i-1) + (1/2*(k1+k2))*p;
    e_RKH(i) = abs(v_RKH(i)-velocidad(t(i)-tc)); %Error que tiene el método 
end
    
%Metodo de Euler
for i = [2:length(t)]
    v_E(i) = v_E(i-1) + f(v_E(i-1))*p;
    e_E(i) = abs(v_E(i)-velocidad(t(i)-tc)); % Error que tieen el método 
    altura(i) = altura(i-1) - v_E(i)*p; % Altura en el frenao magnético 
end

%Metodo de Runge Kutta 4to orden
 for i = [2:length(t)]
    k1 = f(v_RKH(i-1))
    k2 = f(v_RKH(i-1)+(1/2)*p);
    k3 = f(v_RKH(i-1)+(1/2)*p);
    k4 = f(v_RKH(i-1)+p);
    v_RK4(i) = v_RK4(i-1) + 1/6*(k1+2*k2+2*k3+k4)*p;
    e_RK4(i) = abs(v_RK4(i)-velocidad(t(i)-tc)); % Eror que tiene el método 
 end

%Graficas.
plot(app.UIAxes,t0,v_CL(t0),"m")
hold(app.UIAxes,"on")
plot(app.UIAxes,t,velocidad(t-tc),"k")
plot(app.UIAxes,t,v_RKH,"r")
plot(app.UIAxes,t,v_E,"b")
plot(app.UIAxes,t,v_RK4,"g")
legend(app.UIAxes,"Caída Libre","Analítica","Runge-Kutta Heun","Euler","RK4")
hold(app.UIAxes,"off")

plot(app.UIAxes2,v_CL(t0),altura_CL(t0),"b")
hold(app.UIAxes2,"on")
plot(app.UIAxes2,v_E,altura,"c")
plot(app.UIAxes2,jerk(t2-tc)/9.81,altura,"k")
plot(app.UIAxes2,aceleracion_CL,altura_CL(t0),"r")
plot(app.UIAxes2,aceleracion(t2-tc),altura,"g")
legend(app.UIAxes2,"Velocidad Caída Libre (m/s)","Velocidad en campo magnético (m/s)","Jerk (g/s)","Aceleración Caída Libre (m/s^2)","Aceleración en campo magnético (m/s^2)","Location","northwest")
axis(app.UIAxes2,[-30 30 0 90])
hold(app.UIAxes2,"off")

% Errores de los métodos.
app.ErrormximoRKHeun.Value = max(e_RKH)
app.ErrormximoEuler.Value = max(e_E)
app.ErrormximoRK4.Value = max(e_RK4)

% Simulación
t_1 = [0:0.01:tc]
axis(app.UIAxes3,[-1 1 0 55])
rectangle(app.UIAxes3,'Position',[-0.025 0 0.05 50],"EdgeColor",[0 .5 .5])
hold(app.UIAxes3,"on")
for i = [1:length(t_1)]
    y = altura_CL(t_1(i))
    hG = scatter(app.UIAxes3,0,y,"filled")
    drawnow
    delete(hG)
end

yw = 50-h

th = 0.01
i = 0
while yw >= 0
    ya = yw
    yw = ya - velocidad(i)*th
    hG = scatter(app.UIAxes3,0,yw,"filled")
    drawnow
    delete(hG)
    i = i+0.01
end

app.VelocidadFinal.Value = velocidad(i-0.01)
app.VelicidadFinalkmh.Value = velocidad(i-0.01)*3600/1000
app.AceleracionMaxima.Value = aceleracion(0)
app.JerkMaximo.Value = jerk(0)/9.8
app.VelocidadFinalCaidaLibre.Value = v_CL(tc)*3600/1000
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {741, 741};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {275, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AlturadecaidalibreLabel = uilabel(app.LeftPanel);
            app.AlturadecaidalibreLabel.Text = 'Altura de caida libre';
            app.h = uieditfield(app.LeftPanel, 'numeric');
            app.h.Value = 25;
            app.PasodelosmtodosLabel = uilabel(app.LeftPanel);
            app.PasodelosmtodosLabel.Text = 'Paso de los métodos';
            app.p = uieditfield(app.LeftPanel, 'numeric');
            app.p.Value = 0.1;
            app.GraficarButton = uibutton(app.LeftPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.ErrormximoRK4EditFieldLabel = uilabel(app.LeftPanel);
            app.ErrormximoRK4EditFieldLabel.Text = 'Error máximo RK 4';
            app.ErrormximoRK4 = uieditfield(app.LeftPanel, 'numeric');
            app.ErrormximoEulerEditFieldLabel = uilabel(app.LeftPanel);
            app.ErrormximoEulerEditFieldLabel.Text = 'Error máximo Euler';
            app.ErrormximoEuler = uieditfield(app.LeftPanel, 'numeric');
            app.ErrormximoRKHeunEditFieldLabel = uilabel(app.LeftPanel);
            app.ErrormximoRKHeunEditFieldLabel.Text = 'Error máximo RK Heun';
            app.ErrormximoRKHeun = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Velocidad')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'v (m/s), a (m/s^2), jerk(g/s)')
            ylabel(app.UIAxes2, 'Altura')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Altura')
            app.VelocidadFinalLabel = uilabel(app.RightPanel);
            app.VelocidadFinalLabel.Text = 'Velocidad Final';
            app.VelocidadFinal = uieditfield(app.RightPanel, 'numeric');
            app.AceleracinmximaEditFieldLabel = uilabel(app.RightPanel);
            app.AceleracinmximaEditFieldLabel.Text = 'Aceleración máxima';
            app.AceleracionMaxima = uieditfield(app.RightPanel, 'numeric');
            app.JerkmximoEditFieldLabel = uilabel(app.RightPanel);
            app.JerkmximoEditFieldLabel.Text = 'Jerk máximo';
            app.JerkMaximo = uieditfield(app.RightPanel, 'numeric');
            app.VelicidadFinalkmh = uieditfield(app.RightPanel, 'numeric');
            app.EditField_2 = uieditfield(app.RightPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'm/s';
            app.EditField_3 = uieditfield(app.RightPanel, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'km/h';
            app.EditField_4 = uieditfield(app.RightPanel, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 'm/s^2';
            app.EditField_5 = uieditfield(app.RightPanel, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'g/s';
            app.VelocidadFinalCadaLibreEditFieldLabel = uilabel(app.RightPanel);
            app.VelocidadFinalCadaLibreEditFieldLabel.Text = 'Velocidad Final Caída Libre';
            app.VelocidadFinalCaidaLibre = uieditfield(app.RightPanel, 'numeric');
            app.EditField_6 = uieditfield(app.RightPanel, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'km/h';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Entregable3App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end