classdef Montanarusa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Empezar                        matlab.ui.control.Button
        MasaLabel                      matlab.ui.control.Label
        Masa                           matlab.ui.control.NumericEditField
        VelocidadEditFieldLabel        matlab.ui.control.Label
        Velocidad                      matlab.ui.control.NumericEditField
        ukEditFieldLabel               matlab.ui.control.Label
        uk                             matlab.ui.control.NumericEditField
        kEditFieldLabel                matlab.ui.control.Label
        k                              matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        VelocidadEditFieldLabel_2      matlab.ui.control.Label
        V                              matlab.ui.control.NumericEditField
        AceleracinEditFieldLabel       matlab.ui.control.Label
        A                              matlab.ui.control.NumericEditField
        TiempoEditFieldLabel           matlab.ui.control.Label
        T                              matlab.ui.control.NumericEditField
        FLabel                         matlab.ui.control.Label
        Sf                             matlab.ui.control.NumericEditField
        EnergaPotencialEditFieldLabel  matlab.ui.control.Label
        EP                             matlab.ui.control.NumericEditField
        EnergaCinticaEditFieldLabel    matlab.ui.control.Label
        EC                             matlab.ui.control.NumericEditField
        EnergaMecnicaEditFieldLabel    matlab.ui.control.Label
        EM                             matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Empezar
        function EmpezarButtonPushed(app, event)
            %Gráficas del trayecto de la montaña rusa
            x = [0:0.001:12];
            y1 = -0.2083*x.^2+2.9167*x+30;
            plot (app.UIAxes,x,y1,'g');
            xlim (app.UIAxes, [0 66]);
            ylim (app.UIAxes, [0 105]);

            hold (app.UIAxes,"on")
            x = [12:0.001:35];
            y2 = 0.00507261*x.^3-0.25599975*x.^2+1.8701246*x+40.656991;
            plot (app.UIAxes,x,y2,'g');

            x = [35:0.001:56];
            y3 = -0.1176*x.^2+10.824*x-224.71;
            plot (app.UIAxes,x,y3,'g');
            
            %Pedir datos iniciales al usuario
            m = app.Masa.Value
            v = app.Velocidad.Value
            k = app.k.Value
            uk = app.uk.Value
            g = 9.81
            
            %Cálculo inicial de variables
            teta = atan(2.9167);
            r = (((1+(2.9167).^2).^(3/2))/(-0.4166));
            sfxp = -m*g*sin(teta)-uk*abs(m*(g*cos(teta)+(v.^2/r)))-(k*v.^2)
            ap = sfxp/m;
            xp = 0;
            x = 0;
            y = 30;
            t = 0
            EP = m*g*y
            EC = (1/2)*m*v.^2
            E = EP+EC
            P = ((EP)/E)*100
            C = (EC/E)*100
            EM = EP+EC
            
            %Despliegue de variables
            app.EC.Value = EC
            app.EP.Value = EP
            app.EM.Value = EM
            app.V.Value = v
            app.A.Value = ap
            app.T.Value = t
            app.Sf.Value = sfxp
            
            %Rectángulos de energía
            r1 = rectangle(app.UIAxes,'Position',[60 0 5 P],'FaceColor',[0.4660 0.6740 0.1880])
            r2 = rectangle(app.UIAxes,'Position',[60 P 5 C],'FaceColor',[0 0.4470 0.7410])
            delete (r1)
            delete (r2)
            
            %Primera parte del trayecto
            while x <= 12
                %Actualización de variables anteriores
                xpa = xp;
                xa = x;
                va = v;
                apa = ap;
                ra = r;
                dt = 0.001;
                ya = y;
                
                %Cálculos físicos
                xp = xpa+va*dt+(1/2)*apa*dt.^2
                teta = atan(-0.4166*xa+2.9167)
                x = xa + (xp-xpa)*cos(teta)
                y = -0.2083*x.^2+2.9167*x+30
                r = (((1+(-0.4166*x+2.9167).^2).^(3/2))/(-0.4166))
                v = sqrt((va.^2*(1-(uk/abs(ra)+k/m)*(xp-xpa))-2*g*(y-ya)-2*abs(g)*uk*(x-xa))/(1+(uk/abs(r)+k/m)*(xp-xpa)))
                sfxp = -m*g*sin(teta)-uk*abs(m*(g*cos(teta)+(v.^2/r)))-(k*v.^2)
                ap = sfxp/m
                t = t + dt
                EP = m*g*y
                P = ((EP)/E)*100
                EC = (1/2)*m*v.^2
                C = (EC/E)*100
                EM = EP+EC
                
                %Gráfica del carrito y rectángulos
                h1 = plot (app.UIAxes,x,y,"ob")
                r1 = rectangle(app.UIAxes,'Position',[60 0 5 P],'FaceColor',[0.4660 0.6740 0.1880])
                r2 = rectangle(app.UIAxes,'Position',[60 P 5 C],'FaceColor',[0 0.4470 0.7410])
                drawnow
                delete (h1)
                delete (r1)
                delete (r2)
                
                %Despliegue de variables
                app.V.Value = v
                app.A.Value = ap
                app.T.Value = t
                app.Sf.Value = sfxp
                app.EC.Value = EC
                app.EP.Value = EP
                app.EM.Value = EM
            end
            
            %Segunda parte del trayecto
            while x >= 12 & x <= 35
                %Actualización de variables anteriores
                xpa = xp
                xa = x
                va = v
                apa = ap
                ra = r
                dt = 0.001
                ya = y
                
                %Cálculos físicos
                xp = xpa+va*dt+(1/2)*apa*dt.^2
                teta = atan(0.01521783*xa.^2-0.5119995*xa+1.8701246)
                x = xa + (xp-xpa)*cos(teta)
                y = 0.00507261*x.^3-0.25599975*x.^2+1.8701246*x+40.656991
                r = (((1+(-0.01521783*x.^2-0.5119995*x+1.8701246).^2).^(3/2))/(0.0304357*x-0.5119995))
                v = sqrt((va.^2*(1-(uk/abs(ra)+k/m)*(xp-xpa))-2*g*(y-ya)-2*abs(g)*uk*(x-xa))/(1+(uk/abs(r)+k/m)*(xp-xpa)))
                sfxp = -m*g*sin(teta)-uk*abs(m*(g*cos(teta)+(v.^2/r)))-(k*v.^2)
                ap = sfxp/m
                t = t + dt
                EP = m*g*y
                P = ((EP)/E)*100
                EC = (1/2)*m*v.^2
                C = (EC/E)*100
                EM = EP+EC
                
                %Gráfica del carrito y rectángulos
                h2 = plot (app.UIAxes,x,y,"ob")
                r1 = rectangle(app.UIAxes,'Position',[60 0 5 P],'FaceColor',[0.4660 0.6740 0.1880])
                r2 = rectangle(app.UIAxes,'Position',[60 P 5 C],'FaceColor',[0 0.4470 0.7410])
                drawnow
                delete (h2)
                delete (r1)
                delete (r2)
                
                %Despliegue de variables
                app.V.Value = v
                app.A.Value = ap
                app.T.Value = t
                app.Sf.Value = sfxp
                app.EC.Value = EC
                app.EP.Value = EP
                app.EM.Value = EM
            end
            
            %Tercera parte del trayecto
            while x >= 35 & x <= 56
                %Actualización de variables anteriores
                xpa = xp
                xa = x
                va = v
                apa = ap
                ra = r
                dt = 0.001
                ya = y
                
                %Cálculos físicos
                xp = xpa+va*dt+(1/2)*apa*dt.^2
                teta = atan(-0.2352*xa+10.824)
                x = xa + (xp-xpa)*cos(teta)
                y = -0.1176*x.^2+10.824*x-224.71
                r = (((1+(-0.2352*x+10.824).^2).^(3/2))/(-0.2352))
                v = sqrt((va.^2*(1-(uk/abs(ra)+k/m)*(xp-xpa))-2*g*(y-ya)-2*abs(g)*uk*(x-xa))/(1+(uk/abs(r)+k/m)*(xp-xpa)))
                sfxp = -m*g*sin(teta)-uk*abs(m*(g*cos(teta)+(v.^2/r)))-(k*v.^2)
                ap = sfxp/m
                t = t + dt
                EP = m*g*y
                P = ((EP)/E)*100
                EC = (1/2)*m*v.^2
                C = (EC/E)*100
                EM = EP+EC
                
                %Gráfica del carrito y rectángulos
                h3 = plot (app.UIAxes,x,y,"ob")
                r1 = rectangle(app.UIAxes,'Position',[60 0 5 P],'FaceColor',[0.4660 0.6740 0.1880])
                r2 = rectangle(app.UIAxes,'Position',[60 P 5 C],'FaceColor',[0 0.4470 0.7410])
                drawnow
                delete (h3)
                delete (r1)
                delete (r2)
                
                %Despliegue de variables
                app.V.Value = v
                app.A.Value = ap
                app.T.Value = t
                app.Sf.Value = sfxp
                app.EC.Value = EC
                app.EP.Value = EP
                app.EM.Value = EM
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {189, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Empezar = uibutton(app.LeftPanel, 'push');
            app.Empezar.ButtonPushedFcn = createCallbackFcn(app, @EmpezarButtonPushed, true);
            app.Empezar.Text = 'Empezar';
            app.MasaLabel = uilabel(app.LeftPanel);
            app.MasaLabel.Text = 'Masa';
            app.Masa = uieditfield(app.LeftPanel, 'numeric');
            app.VelocidadEditFieldLabel = uilabel(app.LeftPanel);
            app.VelocidadEditFieldLabel.Text = 'Velocidad';
            app.Velocidad = uieditfield(app.LeftPanel, 'numeric');
            app.ukEditFieldLabel = uilabel(app.LeftPanel);
            app.ukEditFieldLabel.Text = 'uk';
            app.uk = uieditfield(app.LeftPanel, 'numeric');
            app.kEditFieldLabel = uilabel(app.LeftPanel);
            app.kEditFieldLabel.Text = 'k';
            app.k = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Montaña Rusa')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.VelocidadEditFieldLabel_2 = uilabel(app.RightPanel);
            app.VelocidadEditFieldLabel_2.Text = 'Velocidad';
            app.V = uieditfield(app.RightPanel, 'numeric');
            app.V.Editable = 'off';
            app.AceleracinEditFieldLabel = uilabel(app.RightPanel);
            app.AceleracinEditFieldLabel.Text = 'Aceleración';
            app.A = uieditfield(app.RightPanel, 'numeric');
            app.A.Editable = 'off';
            app.TiempoEditFieldLabel = uilabel(app.RightPanel);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.T = uieditfield(app.RightPanel, 'numeric');
            app.T.Editable = 'off';
            app.FLabel = uilabel(app.RightPanel);
            app.FLabel.Text = 'ΣF';
            app.Sf = uieditfield(app.RightPanel, 'numeric');
            app.Sf.Editable = 'off';
            app.EnergaPotencialEditFieldLabel = uilabel(app.RightPanel);
            app.EnergaPotencialEditFieldLabel.Text = 'Energía Potencial';
            app.EP = uieditfield(app.RightPanel, 'numeric');
            app.EnergaCinticaEditFieldLabel = uilabel(app.RightPanel);
            app.EnergaCinticaEditFieldLabel.Text = 'Energía Cinética';
            app.EC = uieditfield(app.RightPanel, 'numeric');
            app.EnergaMecnicaEditFieldLabel = uilabel(app.RightPanel);
            app.EnergaMecnicaEditFieldLabel.Text = 'Energía Mecánica';
            app.EM = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Montanarusa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end